classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        RightPanel               matlab.ui.container.Panel
        SaveDataButton           matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        StepPauseEditField       matlab.ui.control.NumericEditField
        StepPauseEditFieldLabel  matlab.ui.control.Label
        micronsLabel_2           matlab.ui.control.Label
        micronsLabel             matlab.ui.control.Label
        HomeMotorButton          matlab.ui.control.Button
        StepSizeEditField        matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel   matlab.ui.control.Label
        SpanEditField            matlab.ui.control.NumericEditField
        SpanEditFieldLabel       matlab.ui.control.Label
        ConnectButton            slrealtime.ui.control.ConnectButton
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpanEditField
        function SpanEditFieldValueChanged(app, event)
            span = app.SpanEditField.Value;
            
        end

        % Callback function
        function StartStopButtonPostStart(app, event)
            BBD302_control_Keithley2400_meas_current;
            
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            x_step = app.StepSizeEditField.Value/1000;
            y_step = app.StepSizeEditField.Value/1000;
        end

        % Callback function: ConnectButton
        function ConnectButtonPostConnect(app, event)
            Connection;
        end

        % Callback function: ConnectButton
        function ConnectButtonPostDisconnect(app, event)
            disconnected;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Control code for measuring spatial photocurrent maps in the SPCM:
% Software for controlling the MLS203 X-Y stage via the BBD302 
% and reading the current output from the Keithley 2400
% By Morgan Blevins, Feb 2024

%% Notes
% - For the BBD303 using the MLS203 stage
% - For the PMTSS photodetector current measured with Keithley2400

%% BB302 control code is via Thorlabs github:
% https://github.com/Thorlabs/Motion_Control_Examples
% BBD30X.m
% Created Date: 2024-01-23
% Last modified date: 2024-01-23
% Matlab Version: R2023b
% Thorlabs DLL version: Kinesis 1.14.44

%% Start of code
%clear all; close all; clc

%% Initialize x-y map for MLS203 stage

% This should be updated based on the current position of the x-y stage
% when the code is run:
x_center = 50.69280; 
y_center = 47.14280;

% Define how big the scanning picture is:

%APP CONTROLLED
span = app.SpanEditField.Value; % mm
%span = 0.004; % mm

% Define the edge coordinates of the picture:
x_min = x_center-span; % mm
x_min = x_center; % mm
y_min = y_center-span; % mm
x_max = x_center+span; % mm
y_max = y_center+span; % mm

% Define the step size for the picture:

%CONTROLLED BY APP
 x_step = app.StepSizeEditField.Value/1000;
 y_step = app.StepSizeEditField.Value/1000;

% Define the [x,y] grid for looping:
x = x_min:x_step:x_max;
y = y_min:y_step:y_max;
XY = meshgrid(x,y); % meshgrid of (x,y) coordinates

%% Initialize vectors for PMT (photodetector) data
meas = ones(length(x),length(y));
meas_str = meas; % for holding the string version of the data
t = meas; % time of the measurement

% define pause time between commands for test
pTime = 0.05;
% pTime = 0.25;


%%% draft code for future %%%
%pos2 = channel2.GetPositionCounter % Get Channel 1 (should be how I eventually get the position from the intial position)
%channel2.GetDevParams
% pause(1);
%motorSettings2 = channel2.LoadMotorConfiguration(channel2.DeviceID);
% pause(1);

% Prompt the user to see if you'd to HOME stage
%dlgTitle    = 'User Question';
%dlgQuestion = 'Home the BBD302 stage?';
%choice = questdlg(dlgQuestion,dlgTitle,'Yes','No', 'Yes');

%IN APP HOME.m
%if contains(choice, 'Yes')
    % Home Motor
 %   fprintf("Homing...\n")
  %  channel1.Home(timeout);
   % fprintf("Homed\n")
    % pause(2);
    
    % Home Motor
    %fprintf("Homing 2...\n")
    %channel2.Home(timeout);
   % fprintf("Homed 2\n")
    % pause(2);
%end

% Initialize measurement with basline reading
%writeline(k, ':READ?');
%data = readline(k);
%meas = str2double(data).*meas;

%% Loop through grid of positions 
for ii = 1:length(x)
    for jj = 1:length(y)
        pos_x = x(ii); % define next x position
        pos_y = y(jj); % define next y position

        %channel1.MoveTo(pos_x, timeout); % move to x position
        %channel2.MoveTo(pos_y, timeout); % move to y position

        pause(pTime); % pause 

        %writeline(k, ':READ?'); % ask for the current value from Keithely 
        %data = readline(k);     % read the current value recieved [string]
        %meas_str(ii,jj) = data; % save data in string form
        meas(ii,jj) = x(ii)^2 + 2*y(jj); % save data in double form 
        t(ii,jj) = toc;         % save time of reading

        pause(pTime); % pause

        % Live plotting:

        %MAY CHANGE !!!!!
        %-(x-x_center).*1000,(y-y_center).*1000 
        imagesc(app.UIAxes,meas); % position in micrometers
        
        set(gca,'YDir','normal')
        ylabel('y (microns)'); xlabel('x (microns)');
    end
end
        end

        % Button down function: RightPanel
        function RightPanelButtonDown(app, event)

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
               [file,path] = uiputfile('*.mat','Save data as');
               if file~=0
                   save([path file]); 
               else
                   disp('User selected Cancel');
               end
               drawnow;
               figure(app.UIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @RightPanelButtonDown, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.RightPanel);
            app.ConnectButton.PostConnectFcn = createCallbackFcn(app, @ConnectButtonPostConnect, true);
            app.ConnectButton.PostDisconnectFcn = createCallbackFcn(app, @ConnectButtonPostDisconnect, true);
            app.SpanEditFieldLabel = uilabel(app.RightPanel);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.RightPanel, 'numeric');
            app.SpanEditField.Limits = [0 100];
            app.SpanEditField.ValueChangedFcn = createCallbackFcn(app, @SpanEditFieldValueChanged, true);
            app.SpanEditField.Tooltip = {'Changes the image''s size'};
            app.SpanEditField.Value = 20;
            app.StepSizeEditFieldLabel = uilabel(app.RightPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.StepSizeEditField.Limits = [0 5];
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Tooltip = {'Changes the step size of the laser in the x and y direction'};
            app.StepSizeEditField.Value = 0.25;
            app.HomeMotorButton = uibutton(app.RightPanel, 'push');
            app.HomeMotorButton.Text = 'Home Motor';
            app.micronsLabel = uilabel(app.RightPanel);
            app.micronsLabel.Text = 'microns';
            app.micronsLabel_2 = uilabel(app.RightPanel);
            app.micronsLabel_2.Text = 'microns';
            app.StepPauseEditFieldLabel = uilabel(app.RightPanel);
            app.StepPauseEditFieldLabel.Text = 'Step Pause';
            app.StepPauseEditField = uieditfield(app.RightPanel, 'numeric');
            app.StepPauseEditField.Limits = [0 1];
            app.StepPauseEditField.Placeholder = '0.1';
            app.StepPauseEditField.Value = 0.1;
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end